classdef fdn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LoadDataMenu                   matlab.ui.container.Menu
        IndexfileMenu                  matlab.ui.container.Menu
        PreloadedDataMenu              matlab.ui.container.Menu
        PublicDataMenu                 matlab.ui.container.Menu
        AnalyzeMenu                    matlab.ui.container.Menu
        ChromatinpartitioningMenu      matlab.ui.container.Menu
        DifferentialexpressionMenu     matlab.ui.container.Menu
        DNfeatureanalysisMenu          matlab.ui.container.Menu
        LarntzPerlmanMenu              matlab.ui.container.Menu
        ABswitchingMenu                matlab.ui.container.Menu
        EffectiveresistanceMenu        matlab.ui.container.Menu
        VisualizeMenu                  matlab.ui.container.Menu
        GenomewideMenu                 matlab.ui.container.Menu
        HiCRNAseqMenu                  matlab.ui.container.Menu
        DNfeatureanalysisMenu_2        matlab.ui.container.Menu
        DataExportMenu                 matlab.ui.container.Menu
        dataInfoMenu                   matlab.ui.container.Menu
        HMenu                          matlab.ui.container.Menu
        kbMenu                         matlab.ui.container.Menu
        MbMenu                         matlab.ui.container.Menu
        RMenu                          matlab.ui.container.Menu
        TPMMenu                        matlab.ui.container.Menu
        countsMenu                     matlab.ui.container.Menu
        RPKMMenu                       matlab.ui.container.Menu
        ExamplesMenu                   matlab.ui.container.Menu
        LarntzPerlmanMenu_2            matlab.ui.container.Menu
        chrlevelcomparisonMenu         matlab.ui.container.Menu
        AddchromosometofigureMenu      matlab.ui.container.Menu
        KarytypegenesetexpressionMenu  matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        DocumentationMenu              matlab.ui.container.Menu
        TextArea_2                     matlab.ui.control.TextArea
        DNvestigatorLabel              matlab.ui.control.Label
    end

    
    properties (Access = public)
        dataInfo % data information structure
        H % Hi-C data structure
        R % RNA-seq data structure
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% general start-up
            %clear
            %close all
            restoredefaultpath
            addpath(genpath('.'))
            rmpath(genpath('./functions/depreciated'))
            
            %% get computer info
            if isunix
                app.dataInfo.delim = '/';
            else
                app.dataInfo.delim = '\';
            end
            
            %% load often used variable
            app.dataInfo.hicCMap = [ones(64,1),[1:-1/63:0]',[1:-1/63:0]'];
            
            %% plot on axes for start up (depreciated, D20190116)
            %createStartUpFig
            
        end

        % Menu selected function: IndexfileMenu
        function IndexfileMenuSelected(app, event)
            % Process Input Data Information
            [app.dataInfo] = fdnLoadUserInput;
            
            % load Hi-C
            [app.H] = fdnLoadHic(app.dataInfo);
            
            % load RNA-seq
            [app.R] = fdnLoadRnaseq(app.dataInfo,app.H);
            
            % SAVE DATA
            savedData.dataInfo = app.dataInfo;
            savedData.H = app.H;
            savedData.R = app.R;
            
            save(sprintf('%s%sdata%s%sData.mat',app.dataInfo.path.output,...
                app.dataInfo.delim,app.dataInfo.delim,...
                app.dataInfo.projName),'savedData','-v7.3')
        end

        % Menu selected function: PreloadedDataMenu
        function PreloadedDataMenuSelected(app, event)
            %% select saved data file
            [file,path] = uigetfile('*.mat','Select saved file');
            
            % wait message
            waitBar = waitbar(0,'loading your saved data...','Name','Loading Data');
            set(findall(waitBar),'Units', 'normalized');    % Change the Units Property of the figure and all the children
            set(waitBar,'Position', [0.25 0.4 0.5 0.08]);
            
            % load data
            load(fullfile(path,file))
            
            % overwrite app data structures
            app.dataInfo = savedData.dataInfo;
            app.H = savedData.H;
            app.R = savedData.R;
            
            close(waitBar)
            
        end

        % Menu selected function: PublicDataMenu
        function PublicDataMenuSelected(app, event)
            publicData = readtable('publicDataIndex.xlsx');
            [indx,~] = listdlg('PromptString','Select Genes to analyze',...
                'ListString',publicData.sample,...
                'ListSize',[500 500]);
            sampleSelect = samples{indx};
        end

        % Menu selected function: chrlevelcomparisonMenu
        function chrlevelcomparisonMenuSelected(app, event)
            D20181213_23x23LpAnalysisV2
        end

        % Menu selected function: AddchromosometofigureMenu
        function AddchromosometofigureMenuSelected(app, event)
            D20181212_addChr2Plot
        end

        % Menu selected function: KarytypegenesetexpressionMenu
        function KarytypegenesetexpressionMenuSelected(app, event)
            D20181119_wntGeneChrDist
        end

        % Menu selected function: ChromatinpartitioningMenu
        function ChromatinpartitioningMenuSelected(app, event)
            % set up dialog box
            prompt = {'Enter partition method:','Enter RNA-seq normalization:',...
                'Divide Chromosome by arm?','Plot results?','Partition split value:'};
            name = 'Input chromatin partitioning parameters';
            formats = struct('type', {}, 'style', {}, 'items', {}, ...
                'format', {}, 'limits', {}, 'size', {});
            
            % partition answers
            formats(1,1).type   = 'list';
            formats(1,1).style = 'popupmenu';
            formats(1,1).items = {'fiedler','pc1'};
            
            % RNA-seq normalization answers
            formats(2,1).type   = 'list';
            formats(2,1).style  = 'popupmenu';
            formats(2,1).items  = {'none','log2'};
            
            % Divide Chromosome answers
            formats(3,1).type   = 'list';
            formats(3,1).style = 'popupmenu';
            formats(3,1).items = {'no','yes'};
            
            % Plot answers
            formats(4,1).type   = 'list';
            formats(4,1).style = 'popupmenu';
            formats(4,1).items = [0,1];
            
            % Partition split value answers
            formats(5,1).type   = 'list';
            formats(5,1).style = 'popupmenu';
            formats(5,1).items = {'sign','ratio','bisection','gap'};
            
            % get user inputs parameters
            defaultanswer = {1, 1, 1, 1, 1};
            [answer, canceled] = inputsdlg(prompt, name, formats, defaultanswer);
            
            partParam.method = formats(1,1).items{answer{1}};
            partParam.rnaSeqNorm = formats(2,1).items{answer{2}};
            partParam.chrDivide = formats(3,1).items{answer{3}};
            partParam.plotFlag = formats(4,1).items(answer{4});
            partParam.fdvSplitMethod = formats(5,1).items{answer{5}};
            app.dataInfo.partParam = partParam;
            
            % run chromatin partitioning
            [app.H,app.R] = fdnChromPartition(app.dataInfo,app.H,app.R,partParam);
            
            % SAVE DATA
            savedData.dataInfo = app.dataInfo;
            savedData.H = app.H;
            savedData.R = app.R;
            
            save(sprintf('%s%sdata%s%sData.mat',app.dataInfo.path.output,...
                app.dataInfo.delim,app.dataInfo.delim,...
                app.dataInfo.projName),'savedData','-v7.3')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadDataMenu = uimenu(app.UIFigure);
            app.LoadDataMenu.Text = 'Load Data';
            app.IndexfileMenu = uimenu(app.LoadDataMenu);
            app.IndexfileMenu.MenuSelectedFcn = createCallbackFcn(app, @IndexfileMenuSelected, true);
            app.IndexfileMenu.Text = 'Index file';
            app.PreloadedDataMenu = uimenu(app.LoadDataMenu);
            app.PreloadedDataMenu.MenuSelectedFcn = createCallbackFcn(app, @PreloadedDataMenuSelected, true);
            app.PreloadedDataMenu.Text = 'Pre-loaded Data';
            app.PublicDataMenu = uimenu(app.LoadDataMenu);
            app.PublicDataMenu.MenuSelectedFcn = createCallbackFcn(app, @PublicDataMenuSelected, true);
            app.PublicDataMenu.Text = 'Public Data';
            app.AnalyzeMenu = uimenu(app.UIFigure);
            app.AnalyzeMenu.Text = 'Analyze';
            app.ChromatinpartitioningMenu = uimenu(app.AnalyzeMenu);
            app.ChromatinpartitioningMenu.MenuSelectedFcn = createCallbackFcn(app, @ChromatinpartitioningMenuSelected, true);
            app.ChromatinpartitioningMenu.Text = 'Chromatin partitioning';
            app.DifferentialexpressionMenu = uimenu(app.AnalyzeMenu);
            app.DifferentialexpressionMenu.Text = 'Differential expression';
            app.DNfeatureanalysisMenu = uimenu(app.AnalyzeMenu);
            app.DNfeatureanalysisMenu.Text = '4DN feature analysis';
            app.LarntzPerlmanMenu = uimenu(app.AnalyzeMenu);
            app.LarntzPerlmanMenu.Text = 'Larntz-Perlman';
            app.ABswitchingMenu = uimenu(app.AnalyzeMenu);
            app.ABswitchingMenu.Text = 'A/B switching';
            app.EffectiveresistanceMenu = uimenu(app.AnalyzeMenu);
            app.EffectiveresistanceMenu.Text = 'Effective resistance';
            app.VisualizeMenu = uimenu(app.UIFigure);
            app.VisualizeMenu.Text = 'Visualize';
            app.GenomewideMenu = uimenu(app.VisualizeMenu);
            app.GenomewideMenu.Text = 'Genome-wide';
            app.HiCRNAseqMenu = uimenu(app.VisualizeMenu);
            app.HiCRNAseqMenu.Text = 'Hi-C + RNA-seq';
            app.DNfeatureanalysisMenu_2 = uimenu(app.VisualizeMenu);
            app.DNfeatureanalysisMenu_2.Text = '4DN feature analysis';
            app.DataExportMenu = uimenu(app.UIFigure);
            app.DataExportMenu.Text = 'Data Export';
            app.dataInfoMenu = uimenu(app.DataExportMenu);
            app.dataInfoMenu.Text = 'dataInfo';
            app.HMenu = uimenu(app.DataExportMenu);
            app.HMenu.Text = 'H';
            app.kbMenu = uimenu(app.HMenu);
            app.kbMenu.Text = '100kb';
            app.MbMenu = uimenu(app.HMenu);
            app.MbMenu.Text = '1Mb';
            app.RMenu = uimenu(app.DataExportMenu);
            app.RMenu.Text = 'R';
            app.TPMMenu = uimenu(app.RMenu);
            app.TPMMenu.Text = 'TPM';
            app.countsMenu = uimenu(app.RMenu);
            app.countsMenu.Text = 'counts';
            app.RPKMMenu = uimenu(app.RMenu);
            app.RPKMMenu.Text = 'RPKM';
            app.ExamplesMenu = uimenu(app.UIFigure);
            app.ExamplesMenu.Text = 'Examples';
            app.LarntzPerlmanMenu_2 = uimenu(app.ExamplesMenu);
            app.LarntzPerlmanMenu_2.Text = 'Larntz-Perlman';
            app.chrlevelcomparisonMenu = uimenu(app.LarntzPerlmanMenu_2);
            app.chrlevelcomparisonMenu.MenuSelectedFcn = createCallbackFcn(app, @chrlevelcomparisonMenuSelected, true);
            app.chrlevelcomparisonMenu.Text = 'chr-level comparison';
            app.AddchromosometofigureMenu = uimenu(app.ExamplesMenu);
            app.AddchromosometofigureMenu.MenuSelectedFcn = createCallbackFcn(app, @AddchromosometofigureMenuSelected, true);
            app.AddchromosometofigureMenu.Text = 'Add chromosome to figure';
            app.KarytypegenesetexpressionMenu = uimenu(app.ExamplesMenu);
            app.KarytypegenesetexpressionMenu.MenuSelectedFcn = createCallbackFcn(app, @KarytypegenesetexpressionMenuSelected, true);
            app.KarytypegenesetexpressionMenu.Text = 'Karytype gene set expression';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.Text = 'Documentation';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Select "Load Data" from the Menu bar above to load local data files or pre-loaded publicly available datasets'};
            app.DNvestigatorLabel = uilabel(app.UIFigure);
            app.DNvestigatorLabel.Text = '4DNvestigator';
        end
    end

    methods (Access = public)

        % Construct app
        function app = fdn

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end